classdef Transfer_Function_Impulse_and_Step_Response_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TWOPORTNETWORKCALCULATORPanel  matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        InfoButton                     matlab.ui.control.Button
        POLESAndZEROSTextArea          matlab.ui.control.TextArea
        POLESAndZEROSTextAreaLabel     matlab.ui.control.Label
        STEPRESPONSEPanel              matlab.ui.container.Panel
        STEPEditField_3                matlab.ui.control.EditField
        UIAxes_5                       matlab.ui.control.UIAxes
        IMPULSERESPONSEPanel           matlab.ui.container.Panel
        ImpulseFUNCTIONEditField_2     matlab.ui.control.EditField
        UIAxes_4                       matlab.ui.control.UIAxes
        TRANSFERFUNCTIONPanel          matlab.ui.container.Panel
        TRANSFERFUNCTIONEditField      matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        CircuitDiagramTextArea         matlab.ui.control.TextArea
        CircuitDiagramTextAreaLabel    matlab.ui.control.Label
        CircuitTypePanel               matlab.ui.container.Panel
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        ParallelLCbutton               matlab.ui.control.ToggleButton
        ParallelRCbutton               matlab.ui.control.ToggleButton
        ParallelRLbutton               matlab.ui.control.ToggleButton
        SeriesLCbutton                 matlab.ui.control.ToggleButton
        SeriesRCbutton                 matlab.ui.control.ToggleButton
        SeriesRLbutton                 matlab.ui.control.ToggleButton
        ParallelCbutton                matlab.ui.control.ToggleButton
        ParallelLbutton                matlab.ui.control.ToggleButton
        ParallelRbutton                matlab.ui.control.ToggleButton
        SeriesCbutton                  matlab.ui.control.ToggleButton
        SeriesLbutton                  matlab.ui.control.ToggleButton
        SeriesRbutton                  matlab.ui.control.ToggleButton
        CompanentValuesPanel           matlab.ui.container.Panel
        RLabel_2                       matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        iLabel_2                       matlab.ui.control.Label
        iLabel                         matlab.ui.control.Label
        time_edit                      matlab.ui.control.NumericEditField
        TimeLabel                      matlab.ui.control.Label
        time_unit                      matlab.ui.control.DropDown
        ZLS                            matlab.ui.control.NumericEditField
        ZSS                            matlab.ui.control.NumericEditField
        ZL                             matlab.ui.control.NumericEditField
        ResistorRLabel_2               matlab.ui.control.Label
        CALCULATEButton                matlab.ui.control.Button
        ZS                             matlab.ui.control.NumericEditField
        ResistorRLabel_3               matlab.ui.control.Label
        ADDButton                      matlab.ui.control.Button
        farad_edit                     matlab.ui.control.NumericEditField
        CapacitorCLabel                matlab.ui.control.Label
        resistor_edit                  matlab.ui.control.NumericEditField
        ResistorRLabel                 matlab.ui.control.Label
        inductor_edit                  matlab.ui.control.NumericEditField
        InductorLLabel                 matlab.ui.control.Label
        resistr_unit                   matlab.ui.control.DropDown
        inductorunit                   matlab.ui.control.DropDown
        faradunit                      matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        Property % Description
        
    ekdevre=0;
    impedance=[];
    total_impedance=[];
    parallel_impedance=0
    impadence_matrix= [1 0; 0 1];

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            
            if  app.SeriesRbutton.Value==true 
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="off";
                app.ekdevre=1;
            elseif app.SeriesLbutton.Value==true 
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="on";
                app.ekdevre=3;
            elseif app.SeriesCbutton.Value==true 
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="off";
                app.ekdevre=5;
            elseif app.SeriesRLbutton.Value==true 
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="on";
                app.ekdevre=7;
            elseif app.SeriesRCbutton.Value==true 
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="off";
                app.ekdevre=9;
            elseif app.SeriesLCbutton.Value==true 
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="on";
                app.ekdevre=11;

            elseif   app.ParallelRbutton.Value==true
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="off";
                app.ekdevre=2;
            elseif  app.ParallelLbutton.Value==true
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="on";
                app.ekdevre=4;
            elseif  app.ParallelCbutton.Value==true
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="off";
                app.ekdevre=6;
            elseif  app.ParallelRLbutton.Value==true
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="off";
                app.inductor_edit.Enable="on";
                app.ekdevre=8;
            elseif  app.ParallelRCbutton.Value==true
                app.resistor_edit.Enable="on";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="off";
                app.ekdevre=10;
            elseif  app.ParallelLCbutton.Value==true
                app.resistor_edit.Enable="off";
                app.farad_edit.Enable="on";
                app.inductor_edit.Enable="on";
                app.ekdevre=12;
            end


        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            F_deger= app.farad_edit.Value;
            L_deger=app.inductor_edit.Value;
            R_deger=app.resistor_edit.Value;
            x=app.faradunit.Value;

            switch x
                case 'mF'
                    F_deger=F_deger*0.001;
                case'μF'
                    F_deger=F_deger*0.000001;
                case'nF'
                    F_deger=F_deger*0.000000001;
                case'pF'
                    F_deger=F_deger*0.000000000001;
            end

            y = app.inductorunit.Value;
            switch y

                case'mH'
                    L_deger=L_deger*0.001;
                case'μH'
                    L_deger=L_deger*0.000001;
                case'nH'
                    L_deger=L_deger*0.000000001;
                case'pH'
                    L_deger=L_deger*0.000000000001;
            end

            z=app.resistr_unit.Value;
            switch z
                case'kOhm'
                    R_deger=R_deger*1000;
            end


            syms   s
            L= L_deger;
            R= R_deger;
            C= F_deger;
            %         s= tf('s');
            switch app.ekdevre
                case 1
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series R ";
                    app.impedance=[1 R; 0 1];
                case 2
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel R ";
                    app.impedance=[1 0; (1/R) 1];
                case 3
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series L ";
                    app.impedance=[1 (L*s); 0 1];
                case 4
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel L ";
                    app.impedance=[1 0; (1/(L*s)) 1];
                case 5
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series C ";
                    app.impedance= [1 (1/(C*s)) ; 0 1];
                case 6
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel C ";
                    app.impedance= [1 0; (1/((1/(C*s)))) 1];
                case 7
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series RL ";
                    app.impedance= [1 (R+(L*s)); 0 1];
                case 8
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel RL ";
                    app.impedance=    [1 0; (1/((R*L*s)/(R+(L*s)))) 1];
                case 9
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series RC ";
                    app.impedance=[1 (R+(1/(C*s))); 0 1];
                case 10
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel RC ";
                    app.impedance= [1 0; (1/(R/(R*s*C+1))) 1];
                case 11
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Series LC ";
                    app.impedance=  [1 (L*s)+(1/(C*s)); 0 1];
                case 12
                    app.CircuitDiagramTextArea.Value= app.CircuitDiagramTextArea.Value + "Parallel LC ";
                    app.impedance=  [1 0; (1/((L*s)/((s^2)*L*C+1))) 1] ;

            end
            app.impadence_matrix=app.impadence_matrix*app.impedance;


%             app.total_impedance=subs(app.impadence_matrix,s, s);
            %          simplify(app.total_impedance);


            %         app.EditField3.Value =sym2double(app.impadence_matrix);

%             app.TRANSFERFUNCTIONEditField.Value=string((app.impadence_matrix(1)));
%              app.IMPULSEFUNCTIONEditField.Value=string((app.impadence_matrix(2)));
%               app.STEPRESPONSEEditField.Value=string((app.impadence_matrix(3)));
%                app.EditField2_4.Value=string((app.impadence_matrix(4)));

        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            timeson=app.time_edit.Value;
           xyz=app.time_unit.Value;
            switch xyz
                case 'ms'
                    timeson= timeson*0.001;
                case'μs'
                    timeson=timeson*0.000001;

            end

            t=0:(0.001)*timeson:timeson;

            syms s x
            zs_deger= app.ZS.Value+(app.ZSS.Value*s);
            zl_deger= app.ZL.Value+(app.ZLS.Value*s);

            transfer_function= (app.impadence_matrix(2,1)*zl_deger)/((app.impadence_matrix(1)+zs_deger)*(app.impadence_matrix(2,2)+zl_deger)-(app.impadence_matrix(1,2)*app.impadence_matrix(2,1)));                    
            transfer_function=simplify(transfer_function);
            impulse_response=ilaplace(transfer_function,s,x);
            impulse_response=simplify(impulse_response);
            step_resp= int(impulse_response,x);


            transfer_function_num = subs(transfer_function, s, t);
            impulse_response_num = subs(impulse_response,x,t);
            step_resp_num= subs(step_resp,x,t);

            plot(app.UIAxes,t,transfer_function_num)
            plot(app.UIAxes_4,t,impulse_response_num)
            plot(app.UIAxes_5,t,step_resp_num)

            [n,d]=numden(transfer_function);


            num=coeffs(n,'All');
            den=coeffs(d,'All');
%             app.EditField2_5.Value=string(n);
%             app.EditField2_6.Value=string(d);

            den=double(den);
            num=double(num);
            
            [num,den] = eqtflength(num,den);
            [z,p,k] = tf2zp(num,den);

            zplane(num,den);
            text(real(z)+0.1,imag(z),"Zero")
            text(real(p)+0.1,imag(p),"Pole")
            app.POLESAndZEROSTextArea.Value= "zeros: "+ z+ " poles: "+ p;

            app.TRANSFERFUNCTIONEditField.Value="Equation: "+ string(transfer_function);
            app.ImpulseFUNCTIONEditField_2.Value="Equation: "+string(impulse_response);
            app.STEPEditField_3.Value="Equation: "+string(step_resp);
%             app.EditField2_8.Value=string(aaa(1));
%             app.EditField2_9.Value=string(aaaa(1));


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CircuitDiagramTextArea.Value=" ";
            app.impadence_matrix= [1 0; 0 1];
            app.POLESAndZEROSTextArea.Value=" ";
            app.resistor_edit.Value=0;
            app.farad_edit.Value=0;
            app.time_edit.Value=0;
            app.inductor_edit.Value=0;
            app.ZS.Value=0;
            app.ZL.Value=0;
            app.ZSS.Value=0;
            app.ZLS.Value=0;
            app.ekdevre=0;
            app.impedance=[];
            app.total_impedance=[];
            app.parallel_impedance=0;

           
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            msgbox("The main purpose of this application is to find the transfer function, impulse response and step response values of the circuit using two port network connections.First, we choose our two port network connection that we want to choose. Then we enter the value of the necessary components and click the add button. After all the circuit components are entered, we enter the ZSource and ZLoad values, in addition, we determine our time interval in the graph and click the calculate button. Related graphs and equations appear in the relevant panels. You can reset the values in the application with the reset button.","Information","help")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TWOPORTNETWORKCALCULATORPanel = uipanel(app.UIFigure);
            app.TWOPORTNETWORKCALCULATORPanel.Title = 'TWO PORT NETWORK CALCULATOR';
            app.CompanentValuesPanel = uipanel(app.TWOPORTNETWORKCALCULATORPanel);
            app.CompanentValuesPanel.Title = 'Companent Values ';
            app.faradunit = uidropdown(app.CompanentValuesPanel);
            app.faradunit.Items = {'F', 'mF', 'μF', 'nF', 'pF'};
            app.faradunit.Value = 'F';
            app.inductorunit = uidropdown(app.CompanentValuesPanel);
            app.inductorunit.Items = {'H', 'mH', 'μH', 'nH', 'pH'};
            app.inductorunit.Value = 'H';
            app.resistr_unit = uidropdown(app.CompanentValuesPanel);
            app.resistr_unit.Items = {'Ohm', 'kOhm'};
            app.resistr_unit.Value = 'Ohm';
            app.InductorLLabel = uilabel(app.CompanentValuesPanel);
            app.InductorLLabel.Text = 'Inductor(L) :';
            app.inductor_edit = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.ResistorRLabel = uilabel(app.CompanentValuesPanel);
            app.ResistorRLabel.Text = 'Resistor(R) :';
            app.resistor_edit = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.CapacitorCLabel = uilabel(app.CompanentValuesPanel);
            app.CapacitorCLabel.Text = 'Capacitor(C) :';
            app.farad_edit = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.farad_edit.Tooltip = {''};
            app.ADDButton = uibutton(app.CompanentValuesPanel, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.ResistorRLabel_3 = uilabel(app.CompanentValuesPanel);
            app.ResistorRLabel_3.Text = 'ZS :';
            app.ZS = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.CALCULATEButton = uibutton(app.CompanentValuesPanel, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.ResistorRLabel_2 = uilabel(app.CompanentValuesPanel);
            app.ResistorRLabel_2.Text = 'ZL :';
            app.ZL = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.ZSS = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.ZSS.Value = 10;
            app.ZLS = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.time_unit = uidropdown(app.CompanentValuesPanel);
            app.time_unit.Items = {'s', 'ms', 'μs'};
            app.time_unit.Value = 's';
            app.TimeLabel = uilabel(app.CompanentValuesPanel);
            app.TimeLabel.Text = 'Time: ';
            app.time_edit = uieditfield(app.CompanentValuesPanel, 'numeric');
            app.iLabel = uilabel(app.CompanentValuesPanel);
            app.iLabel.Text = 'i:';
            app.iLabel_2 = uilabel(app.CompanentValuesPanel);
            app.iLabel_2.Text = 'i:';
            app.RLabel = uilabel(app.CompanentValuesPanel);
            app.RLabel.Text = 'R:';
            app.RLabel_2 = uilabel(app.CompanentValuesPanel);
            app.RLabel_2.Text = 'R:';
            app.CircuitTypePanel = uipanel(app.TWOPORTNETWORKCALCULATORPanel);
            app.CircuitTypePanel.Title = 'Circuit Type';
            app.ButtonGroup_2 = uibuttongroup(app.CircuitTypePanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.SeriesRbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesRbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'res_seri.jpg');
            app.SeriesRbutton.Text = 'Series R';
            app.SeriesRbutton.Value = true;
            app.SeriesLbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesLbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'inductor_seri.jpg');
            app.SeriesLbutton.Text = 'Series L';
            app.SeriesCbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'cap_seri.jpg');
            app.SeriesCbutton.Text = 'Series C';
            app.ParallelRbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelRbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'res_parallel.jpg');
            app.ParallelRbutton.Text = 'Parallel R';
            app.ParallelLbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelLbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'ind_parallel.jpg');
            app.ParallelLbutton.Text = 'Parallel L';
            app.ParallelCbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'cap_parallel.jpg');
            app.ParallelCbutton.Text = 'Parallel C';
            app.SeriesRLbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesRLbutton.Icon = fullfile(pathToMLAPP, 'RL.jpg');
            app.SeriesRLbutton.Text = 'Series RL';
            app.SeriesRCbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesRCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'rc_seeri.jpg');
            app.SeriesRCbutton.Text = 'Series RC';
            app.SeriesLCbutton = uitogglebutton(app.ButtonGroup_2);
            app.SeriesLCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'lc_seri.jpg');
            app.SeriesLCbutton.Text = 'Series LC';
            app.ParallelRLbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelRLbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'RL_parallel.jpg');
            app.ParallelRLbutton.Text = 'Parallel RL';
            app.ParallelRCbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelRCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'rc_parallel.jpg');
            app.ParallelRCbutton.Text = 'Parallel RC';
            app.ParallelLCbutton = uitogglebutton(app.ButtonGroup_2);
            app.ParallelLCbutton.Icon = fullfile(pathToMLAPP, 'New folder', 'lxc_parallel.jpg');
            app.ParallelLCbutton.Text = 'Parallel LC';
            app.CircuitDiagramTextAreaLabel = uilabel(app.TWOPORTNETWORKCALCULATORPanel);
            app.CircuitDiagramTextAreaLabel.Text = 'Circuit Diagram';
            app.CircuitDiagramTextArea = uitextarea(app.TWOPORTNETWORKCALCULATORPanel);
            app.TRANSFERFUNCTIONPanel = uipanel(app.TWOPORTNETWORKCALCULATORPanel);
            app.TRANSFERFUNCTIONPanel.Title = 'TRANSFER FUNCTION';
            app.UIAxes = uiaxes(app.TRANSFERFUNCTIONPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TRANSFERFUNCTIONEditField = uieditfield(app.TRANSFERFUNCTIONPanel, 'text');
            app.IMPULSERESPONSEPanel = uipanel(app.TWOPORTNETWORKCALCULATORPanel);
            app.IMPULSERESPONSEPanel.Title = 'IMPULSE RESPONSE';
            app.UIAxes_4 = uiaxes(app.IMPULSERESPONSEPanel);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ImpulseFUNCTIONEditField_2 = uieditfield(app.IMPULSERESPONSEPanel, 'text');
            app.STEPRESPONSEPanel = uipanel(app.TWOPORTNETWORKCALCULATORPanel);
            app.STEPRESPONSEPanel.Title = 'STEP RESPONSE';
            app.UIAxes_5 = uiaxes(app.STEPRESPONSEPanel);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.STEPEditField_3 = uieditfield(app.STEPRESPONSEPanel, 'text');
            app.POLESAndZEROSTextAreaLabel = uilabel(app.TWOPORTNETWORKCALCULATORPanel);
            app.POLESAndZEROSTextAreaLabel.Text = 'POLES And ZEROS';
            app.POLESAndZEROSTextArea = uitextarea(app.TWOPORTNETWORKCALCULATORPanel);
            app.InfoButton = uibutton(app.TWOPORTNETWORKCALCULATORPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.Image2 = uiimage(app.TWOPORTNETWORKCALCULATORPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'KhasLogo1.png');
            app.ResetButton = uibutton(app.TWOPORTNETWORKCALCULATORPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transfer_Function_Impulse_and_Step_Response_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end